classdef view_TWOMDataviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DatainputPanel         matlab.ui.container.Panel
        OpendatafileButton     matlab.ui.control.Button
        SelecttypeofdataLabel  matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        PullingspeedmsLabel    matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        DataoutputPanel        matlab.ui.container.Panel
        ExportdatamButton      matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: OpendatafileButton
        function OpendatafileButtonPushed(app, event)
            clear
            data=openandreadtdmsfile();
        end

        % Button pushed function: ExportdatamButton
        function ExportdatamButtonPushed(app, event)
            prompt = {'Please put filepath here'};
            title = 'Input';
            dims = [1 100];
            definput = {'C:\Users\User1\Desktop\testfolder'};
            answer = inputdlg(prompt,title,dims,definput);
            save(strcat(answer{1},'\workspace'));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DatainputPanel = uipanel(app.UIFigure);
            app.DatainputPanel.Title = 'Data input';
            app.OpendatafileButton = uibutton(app.DatainputPanel, 'push');
            app.OpendatafileButton.ButtonPushedFcn = createCallbackFcn(app, @OpendatafileButtonPushed, true);
            app.OpendatafileButton.Text = 'Open datafile';
            app.SelecttypeofdataLabel = uilabel(app.DatainputPanel);
            app.SelecttypeofdataLabel.Text = 'Select type of data';
            app.DropDown = uidropdown(app.DatainputPanel);
            app.DropDown.Items = {'Bead Tracking', 'Piezo Tracking', 'MCLvoltage Tracking'};
            app.DropDown.Value = 'Bead Tracking';
            app.PullingspeedmsLabel = uilabel(app.DatainputPanel);
            app.PullingspeedmsLabel.Text = 'Pulling speed (µm/s):';
            app.EditField = uieditfield(app.DatainputPanel, 'numeric');
            app.DataoutputPanel = uipanel(app.UIFigure);
            app.DataoutputPanel.Title = 'Data output';
            app.ExportdatamButton = uibutton(app.DataoutputPanel, 'push');
            app.ExportdatamButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdatamButtonPushed, true);
            app.ExportdatamButton.Text = 'Export data to *.m';
        end
    end

    methods (Access = public)

        % Construct app
        function app = view_TWOMDataviewer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end